classdef IlastikValidator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        NextButton                    matlab.ui.control.Button
        PreviousButton                matlab.ui.control.Button
        LoadcsvfilesButton            matlab.ui.control.Button
        LoadImagesButton              matlab.ui.control.Button
        csvimagesnamesconverterPanel  matlab.ui.container.Panel
        pretextEditField              matlab.ui.control.EditField
        posttextEditField             matlab.ui.control.EditField
        ImagefileLampLabel            matlab.ui.control.Label
        ImagefileLamp                 matlab.ui.control.Lamp
        ImagetypeEditFieldLabel       matlab.ui.control.Label
        ImagetypeEditField            matlab.ui.control.EditField
        preEditField_2Label           matlab.ui.control.Label
        preEditField                  matlab.ui.control.NumericEditField
        postEditFieldLabel            matlab.ui.control.Label
        postEditField                 matlab.ui.control.NumericEditField
        subplotButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        file % filename or cellarray of file name
        path % file path
        Imagefiles % cell array of image file names
        CurrentImage = 1 %the image number to display
        ImageData %store the image data
        DataTable %datatable to store the imported data
        importflag = 0 %indicate whether the csv files has already been imported
    end
    
    methods (Access = private)
        
        function Image_to_plot = CutImage(~,DataLine,imagedata)
            %disp([DataLine.BoundingBoxMinimum_1,DataLine.BoundingBoxMaximum_1,DataLine.BoundingBoxMinimum_0,DataLine.BoundingBoxMaximum_0])
            Image_to_plot = imagedata(DataLine.BoundingBoxMinimum_1+1:DataLine.BoundingBoxMaximum_1+1,DataLine.BoundingBoxMinimum_0+1:DataLine.BoundingBoxMaximum_0+1);   
        end
        
        function plot_the_image(~,h,image_to_plot)
            imagesc(h,image_to_plot);
            h.Visible = 'off';
            h.DataAspectRatio = [1,1,1];
            [~,~,depth] = size(image_to_plot);
            %disp(depth)
            if depth == 1
                h.Colormap = gray;
            end
        end
        
        %function that find the common part of two file names spits out a 1x2 matrix with the first
        %number being counted from the start and the second one from the
        %end, everything between those two number are differnet in the file
        %names
        function startend = commonnames(~,cell1,cell2)
            if iscell(cell1)
                cell1 = cell2mat(cell1);
            end
            if iscell(cell2)
                cell2 = cell2mat(cell2);
            end
            commonLen = min(length(cell1),length(cell2));
            lodicalarrayfwd = ~(cell1(1:commonLen) == cell2(1:commonLen));
            lodicalarraybck = ~(cell1(end-(commonLen-1):end) == cell2(end-(commonLen-1):end));
            startend = [find(lodicalarrayfwd,1)-1,((commonLen-1)-find(lodicalarraybck,1,'last'))];
        end
        
        function check_image_filename(app)
            first = app.preEditField.Value + 1;
            last = app.postEditField.Value + 1;
            nr_files = length(app.file);
            imagefiles = {};
            for j=1:nr_files
                singlefile = cell2mat(app.file(j));
                imagefiles(j) = cellstr([singlefile(first:end-last) '.' app.ImagetypeEditField.Value]);
            end
            FileExistLogical = zeros(nr_files,1);
            for k=1:nr_files
                FileExistLogical(k) = isfile([app.path cell2mat(imagefiles(j))]);
            end
            
            if mean(FileExistLogical) == 1
                app.ImagefileLamp.Color = [0 1 0];
                app.Imagefiles = imagefiles;
                app.LoadImagesButton.Enable = true;
                
            else 
                app.ImagefileLamp.Color = [1 0 0];
                
            end
            
            
%             path = cell(length(app.file),1);
%             path(:) = 

        end
        
        function flag = import_csv_2_table(app)
            %load the table file if the image files exist
            if app.ImagefileLamp.Color(2) == 1
                disp('importing csv files')
                for k=1:length(app.file)
                    csvdata = readtable([app.path,cell2mat(app.file(k))]);
                    csvdata.ImageIndex = repmat(k,height(csvdata),1);
                    if ismember('UserLabel',csvdata.Properties.VariableNames)
                        csvdata = removevars(csvdata,'UserLabel')
                    end
                    if k ~= 1
                        app.DataTable = vertcat(app.DataTable, csvdata);
                    else
                        app.DataTable = csvdata;
                    end
                end
                flag = 1;
            else
                disp('not importing csv files')
            end
            disp(flag)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadcsvfilesButton
        function LoadcsvfilesButtonPushed(app, event)
            %get the csv files
            [app.file,app.path,idx] = uigetfile('*.csv','Select all the applicable csv files','MultiSelect','on');
            if idx == 0
                return
            end
            
            %generate a start guess for the image file name
            if iscell(app.file)
                %startend = cell2mat(cellfun(@commonnames, app.file, app.file(end:-1:1),'UniformOutput',false));
                startend = zeros(length(app.file),2);
                for j=1:length(app.file)
                    
                    if ~(j == (length(app.file)-j+1))
                        startend(j,:)=commonnames(app,app.file(j),app.file(end-j+1));
                    end
                end
                testfile = cell2mat(app.file(1));
            else
                startend = [14,9];
                testfile = app.file;
            end
            app.preEditField.Value = min(nonzeros(startend(:,1)));
            app.postEditField.Value = min(nonzeros(startend(:,2)));
            app.pretextEditField.Value = testfile(1:nonzeros(startend(:,1)));
            app.posttextEditField.Value = testfile(end-nonzeros(startend(:,2)):end);
            check_image_filename(app)
            import_csv_2_table(app)
            assignin('base',"data",app.DataTable)
            
        end

        % Button pushed function: LoadImagesButton
        function LoadImagesButtonPushed(app, event)
            %load first image
            Image1 = imread([app.path cell2mat(app.Imagefiles(1))]);
            plot_the_image(app,app.UIAxes,Image1)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.CurrentImage = app.CurrentImage +1;
            if app.CurrentImage > length(app.Imagefiles)
                app.CurrentImage = 1;
            end
            Image1 = imread([app.path cell2mat(app.Imagefiles(app.CurrentImage))]);
            plot_the_image(app,app.UIAxes,Image1)
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.CurrentImage = app.CurrentImage -1;
            if app.CurrentImage < 1
                app.CurrentImage = length(app.Imagefiles);
            end
            Image1 = imread([app.path cell2mat(app.Imagefiles(app.CurrentImage))]);
            plot_the_image(app,app.UIAxes,Image1)
        end

        % Button pushed function: subplotButton
        function subplotButtonPushed(app, event)
            %let's start by plotting a simple functino that just shows a
            %10 by 10 version of the images drawn random.
            figure;
            subplot(10,10,1);
            indexes=randperm(height(app.DataTable),100);
            data_sub=app.DataTable(indexes,:);
            imagenr = data_sub.ImageIndex(1);
            image = imread([app.path cell2mat(app.Imagefiles(imagenr))]);
            for j=1:100
                if imagenr ~= data_sub.ImageIndex(j)
                    imagenr = data_sub.ImageIndex(j);
                    image = imread([app.path cell2mat(app.Imagefiles(imagenr))]);
                end
                h=subplot(10,10,j);
                plot_the_image(app,h,CutImage(app,data_sub(j,:),image));
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = '<';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = '>';
            app.LoadcsvfilesButton = uibutton(app.UIFigure, 'push');
            app.LoadcsvfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcsvfilesButtonPushed, true);
            app.LoadcsvfilesButton.Text = 'Load csv files';
            app.LoadImagesButton = uibutton(app.UIFigure, 'push');
            app.LoadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesButtonPushed, true);
            app.LoadImagesButton.Enable = 'off';
            app.LoadImagesButton.Tooltip = {'Load CSV files first'};
            app.LoadImagesButton.Text = 'Load Images';
            app.csvimagesnamesconverterPanel = uipanel(app.UIFigure);
            app.csvimagesnamesconverterPanel.Title = 'csv - > images names converter';
            app.pretextEditField = uieditfield(app.csvimagesnamesconverterPanel, 'text');
            app.posttextEditField = uieditfield(app.csvimagesnamesconverterPanel, 'text');
            app.ImagefileLampLabel = uilabel(app.csvimagesnamesconverterPanel);
            app.ImagefileLampLabel.Text = 'Image file';
            app.ImagefileLamp = uilamp(app.csvimagesnamesconverterPanel);
            app.ImagetypeEditFieldLabel = uilabel(app.csvimagesnamesconverterPanel);
            app.ImagetypeEditFieldLabel.Text = 'Image type';
            app.ImagetypeEditField = uieditfield(app.csvimagesnamesconverterPanel, 'text');
            app.ImagetypeEditField.Value = 'tif';
            app.preEditField_2Label = uilabel(app.csvimagesnamesconverterPanel);
            app.preEditField_2Label.Text = 'pre';
            app.preEditField = uieditfield(app.csvimagesnamesconverterPanel, 'numeric');
            app.postEditFieldLabel = uilabel(app.csvimagesnamesconverterPanel);
            app.postEditFieldLabel.Text = 'post';
            app.postEditField = uieditfield(app.csvimagesnamesconverterPanel, 'numeric');
            app.subplotButton = uibutton(app.UIFigure, 'push');
            app.subplotButton.ButtonPushedFcn = createCallbackFcn(app, @subplotButtonPushed, true);
            app.subplotButton.Text = 'subplot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IlastikValidator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end